classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        CurrentXEditFieldLabel  matlab.ui.control.Label
        CurrentXEditField       matlab.ui.control.NumericEditField
        CurrentYEditFieldLabel  matlab.ui.control.Label
        CurrentYEditField       matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel  matlab.ui.control.Label
        StepSizeEditField       matlab.ui.control.NumericEditField
        StartButton             matlab.ui.control.Button
        DataKnobLabel           matlab.ui.control.Label
        DataKnob                matlab.ui.control.Knob
        MarkerSettingsPanel     matlab.ui.container.Panel
        SetColorButton          matlab.ui.control.StateButton
        MarkerDropDownLabel     matlab.ui.control.Label
        MarkerDropDown          matlab.ui.control.DropDown
        SizeSpinnerLabel        matlab.ui.control.Label
        SizeSpinner             matlab.ui.control.Spinner
    end


    properties (Access = private)
        h;
        ha;
        Delta;
        Step;

        Running(1,1) logical = false;
        UserData;
    end
    
    methods (Access = private)
    
        function updatePlot(app)
            app.h = plot(app.UIAxes, app.Running,...
                    'Color', app.SetColorButtom.Value,...
                    'Marker', app.MarkerDropDown.Value,...
                    'MarkerSize', app.SizeSpinner.Value);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            x =[0 0];
            app.ha = app.UIAxes;
            app.h = plot(app.ha, x(1), x(2), 'o', 'MarkerSize', 5,...
	           'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'k');

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            xx = 1; yy = 1;
            axis(app.ha,[-xx xx -yy yy]);
            if app.Running
                app.Running = false;
                app.StartButton.Text = 'Start';
            else
                app.Running = true;
                app.StartButton.Text = 'Stop';
                
                step = app.Step;
                x = [0 0];
                while app.Running
                    theta = rand*2*pi;
                    x = x + step*[cos(theta) sin(theta)];
                    app.h.XData = x(1);
                    app.h.YData = x(2);
                    app.CurrentXEditField.Value = x(1);
                    app.CurrentYEditField.Value = x(2);
                    if x(1) < app.ha.XLim(1)
                        app.ha.XLim(1) = app.ha.XLim(1) - app.Delta;
                    elseif x(1) > app.ha.XLim(2)
                        app.ha.XLim(2) = app.ha.XLim(2) + app.Delta;
                    end
                    
                    if x(2) < app.ha.YLim(1)
                        app.ha.YLim(2) = app.ha.YLim(2) + app.Delta;
                    elseif x(2) > app.ha.YLim(2)
                        app.ha.YLim(2) = app.ha.YLim(2) + app.Delta;
                    end
                    
                    drawnow
                end
%            updatePlot(app)
            end
            
        end

        % Value changed function: CurrentXEditField
        function CurrentXEditFieldValueChanged(app, event)
            value = app.CurrentXEditField.Value;
%             value = app.Running.XData;
%             % Print the coordinates of the point
%             diplay(value);

        end

        % Value changed function: CurrentYEditField
        function CurrentYEditFieldValueChanged(app, event)
            value = app.CurrentYEditField.Value;
%             value = app.Running.YData;
%             % Print the coordinates of the point
%             diplay(value);
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            value = app.StepSizeEditField.Value;
            if value >10 
                uialert(app.UIFigure, me.message, 'Error');
            elseif value <= 0
                uialert(app.UIFigure, me.message, 'Error');
            else
                app.Step = value
            end
        end

        % Value changed function: MarkerDropDown
        function MarkerDropDownValueChanged(app, event)
            value = app.MarkerDropDown.Value;
        end

        % Value changed function: SizeSpinner
        function SizeSpinnerValueChanged(app, event)
            value = app.SizeSpinner.Value;
            
        end

        % Value changed function: SetColorButton
        function SetColorButtonValueChanged(app, event)
            value = app.SetColorButton.Value;
            value = uisetcolor;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Random Walk')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.CurrentXEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentXEditFieldLabel.Text = 'Current X';
            app.CurrentXEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentXEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentXEditFieldValueChanged, true);
            app.CurrentXEditField.Editable = 'off';
            app.CurrentYEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentYEditFieldLabel.Text = 'Current Y';
            app.CurrentYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentYEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentYEditFieldValueChanged, true);
            app.CurrentYEditField.Editable = 'off';
            app.StepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DataKnobLabel = uilabel(app.UIFigure);
            app.DataKnobLabel.Text = 'Data';
            app.DataKnob = uiknob(app.UIFigure, 'continuous');
            app.DataKnob.Limits = [2 20];
            app.DataKnob.Value = 2;
            app.MarkerSettingsPanel = uipanel(app.UIFigure);
            app.MarkerSettingsPanel.Title = 'Marker Settings';
            app.SetColorButton = uibutton(app.MarkerSettingsPanel, 'state');
            app.SetColorButton.ValueChangedFcn = createCallbackFcn(app, @SetColorButtonValueChanged, true);
            app.SetColorButton.Text = 'Set Color';
            app.MarkerDropDownLabel = uilabel(app.MarkerSettingsPanel);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.MarkerSettingsPanel);
            app.MarkerDropDown.Items = {'Circle', 'Plus'};
            app.MarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerDropDownValueChanged, true);
            app.MarkerDropDown.Value = 'Circle';
            app.SizeSpinnerLabel = uilabel(app.MarkerSettingsPanel);
            app.SizeSpinnerLabel.Text = 'Size';
            app.SizeSpinner = uispinner(app.MarkerSettingsPanel);
            app.SizeSpinner.Limits = [2 50];
            app.SizeSpinner.ValueDisplayFormat = '%.0f';
            app.SizeSpinner.ValueChangedFcn = createCallbackFcn(app, @SizeSpinnerValueChanged, true);
            app.SizeSpinner.Value = 2;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end