classdef BoA4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BasinsofAttractionUIFigure  matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        PlotButton                  matlab.ui.control.Button
        GridPanel                   matlab.ui.container.Panel
        mEditFieldLabel             matlab.ui.control.Label
        mEditField                  matlab.ui.control.NumericEditField
        nEditFieldLabel             matlab.ui.control.Label
        nEditField                  matlab.ui.control.NumericEditField
        XaxisEditFieldLabel         matlab.ui.control.Label
        XaxisEditField              matlab.ui.control.EditField
        YaxisEditFieldLabel         matlab.ui.control.Label
        YaxisEditField              matlab.ui.control.EditField
        GridCheckBox                matlab.ui.control.CheckBox
        ToleranceEditFieldLabel     matlab.ui.control.Label
        ToleranceEditField          matlab.ui.control.NumericEditField
        BoundaryMEditFieldLabel     matlab.ui.control.Label
        BoundaryMEditField          matlab.ui.control.NumericEditField
        CoefficientsEditFieldLabel  matlab.ui.control.Label
        CoefficientsEditField       matlab.ui.control.EditField
        IterationsEditFieldLabel    matlab.ui.control.Label
        IterationsEditField         matlab.ui.control.NumericEditField
        RootsValueLabel             matlab.ui.control.Label
        RootsValueListBox           matlab.ui.control.ListBox
        ColorPanel                  matlab.ui.container.Panel
        RootsDropDownLabel          matlab.ui.control.Label
        RootsDropDown               matlab.ui.control.DropDown
        Basin1DropDownLabel         matlab.ui.control.Label
        Basin1DropDown              matlab.ui.control.DropDown
        Basin2DropDownLabel         matlab.ui.control.Label
        Basin2DropDown              matlab.ui.control.DropDown
        Basin3DropDownLabel         matlab.ui.control.Label
        Basin3DropDown              matlab.ui.control.DropDown
        Basin4DropDownLabel         matlab.ui.control.Label
        Basin4DropDown              matlab.ui.control.DropDown
        Basin5DropDownLabel         matlab.ui.control.Label
        Basin5DropDown              matlab.ui.control.DropDown
        Basin6DropDownLabel         matlab.ui.control.Label
        Basin6DropDown              matlab.ui.control.DropDown
        AnimationCheckBox           matlab.ui.control.CheckBox
        RootsCheckBox               matlab.ui.control.CheckBox
        RootsStyleKnobLabel         matlab.ui.control.Label
        RootsStyleKnob              matlab.ui.control.DiscreteKnob
        ElapsedTimeEditFieldLabel   matlab.ui.control.Label
        ElapsedTimeEditField        matlab.ui.control.NumericEditField
        CurrentIterationLabel       matlab.ui.control.Label
        CurrentIterationEditField   matlab.ui.control.NumericEditField
        StopButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        Xaxis(1, 2) = [-1.5 1.5]        %Limits of the x-axis
        Yaxis(1, 2) = [-1.5 1.5]        %Limits of the y-axis
        GridM = 500;                    %Number of grid points m
        GridN = 500;                    %Number of grid points n
        Tol = 1e-5;                     %Tolerance for convergence
        M = 1000;                       %Bound for divergence
        Pol = [1, 0, 0, -1];            %Polynomial coefficints
        Depth = 30;                     %Iterations of Newton method
        RootColor = 'black';            %Roots coloring
        Rootson = 0;                    %Roots ploting on/off
        RootStyle = 'o';                %Roots style
        Colormap(7,3);                  %Colormap for plotting
        Animation = 0;                  %Animation on/off
        Roots;                          %Rootvalues
        Stop = 0;                       %Stop iteration 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Write the default values to text boxes
            app.XaxisEditField.Value = strcat(num2str(app.Xaxis(1)), ',', num2str(app.Xaxis(2)));
            app.YaxisEditField.Value = strcat(num2str(app.Yaxis(1)), ',', num2str(app.Yaxis(2)));
            app.mEditField.Value = app.GridM;
            app.nEditField.Value = app.GridN;
            app.ToleranceEditField.Value = app.Tol;
            app.BoundaryMEditField.Value = app.M;
            app.IterationsEditField.Value = app.Depth;
            app.Roots = roots(app.Pol);
            app.Colormap = [1 1 1; 1 0 0; 0 0 1; 0 1 0; 1 1 0; 0 1 1; 1 0 1];


            %Set the axis limits
            app.UIAxes.XLim = app.Xaxis;
            app.UIAxes.YLim = app.Yaxis;
            %Color choices for roots
            app.RootsDropDown.Items = {'black', 'yellow', 'blue', 'red', 'green'};
           
        end

        % Value changed function: XaxisEditField
        function XaxisEditFieldValueChanged(app, event)
            value = app.XaxisEditField.Value;
            xval = str2double(strsplit(value, ','));
            oldx = app.Xaxis; % Stores the old value in case of error
            % Check correctness of the input
            xerr = ~(isnumeric(xval) && ~any(isnan(xval)) && ~any(isinf(xval)) && isreal(xval));
            errmsg = 'Input must be two real numbers separated with a comma.';
            
            % Check that input value is real number, not NaN, not Inf and array length is correct
            try
                
                if xerr
                    error(' ');
                else
                     app.Xaxis = xval;
                    
                    %Set the axis limits
                    app.UIAxes.XLim = app.Xaxis; 
                end
                
            catch
                % Error dialog window in case of error
                errordlg(errmsg, 'Invalid input!');
                % Assign the old value and write it to the text box
                app.Xaxis = oldx;
                app.XaxisEditField.Value = strcat(num2str(app.Xaxis(1)), ',', num2str(app.Xaxis(2)));
          
            end
            
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %Disable plot button during the plotting
            app.PlotButton.Enable = 'off';
            %Enable stop button
            app.StopButton.Enable = 'on';
            %generate the grids
            np = length(app.Pol) - 1;
            dp = polyder(app.Pol);
            r = roots(app.Pol);
            x = linspace(app.Xaxis(1), app.Xaxis(2), app.GridM);
            y = linspace(app.Yaxis(1), app.Yaxis(2), app.GridN);
            C = zeros(length(y), length(x), 'uint8');                       
            [X, Y] = meshgrid(x, y);
            Z = X + 1i*Y;
            %get the colormap values
            cmap = app.Colormap(1:np + 1, :);
            cmap(np + 2,:) = [0 0 0];
            colormap(app.UIAxes, cmap);
            % Removes the line between roots
            app.UIAxes.LineStyleOrder = '+';
            %start timing for iteration
            tic

            for j = 1:app.Depth
                Z = Z - polyval(app.Pol, Z)./(polyval(dp, Z) + eps); % To avoid division by zero
                % Write number of iteration to Current Iteration box
                app.CurrentIterationEditField.Value = j;
                for k = 1:np
                    C(abs(Z - r(k)) < app.Tol) = k;
                end
                C(abs(Z) > app.M) = np + 1;
                %Plot every iteration if animation is on
                if app.Animation
                    image(app.UIAxes, x, y, C);
                    drawnow
                end
                % Exit loop if stop button pressed
                if app.Stop
                    break;
                end
                %Little pause to give time for the stop button
                pause(0.0001)
            end
            app.ElapsedTimeEditField.Value = toc;%get the elapsedtime  
            %Plot the basins
            image(app.UIAxes, x, y, C);
            %Write roots values to list box
            for i = 1: np
                    app.RootsValueListBox.Items{1, i} = num2str(app.Roots(i));
            end
            % Plot roots if roots on
            if app.Rootson
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, r, 'Marker', app.RootStyle, 'MarkerFaceColor', app.RootColor, 'MarkerEdgeColor', app.RootColor)
                hold(app.UIAxes, 'off');
            end

            %Enable plot button
            app.PlotButton.Enable = 'on';
            %Disable the stop button
            app.StopButton.Enable = 'off';
            app.Stop = 0;
        end

        % Value changed function: YaxisEditField
        function YaxisEditFieldValueChanged(app, event)
            value = app.YaxisEditField.Value;
            yval = str2double(strsplit(value, ','));
            oldy = app.Yaxis; % Stores the old value in case of error
            % Check correctness of the input
            yerr = ~(isnumeric(yval) && ~any(isnan(yval)) && ~any(isinf(yval)) && isreal(yval));
            errmsg = 'Input must be two real numbers separated with a comma.';
            
            % Check that input value is real number, not NaN, not Inf and array length is correct

            try
                
                if yerr
                    error(' ');
                    
                else
                    app.Yaxis = yval;
          
                    %Set the axis limits
                    app.UIAxes.YLim = app.Yaxis;  
                end
                
            catch
                % Error dialog window in case of error         
                errordlg(errmsg, 'Invalid input!');
                % Assign the old value and write it to the text box
                app.Yaxis = oldy;
                app.YaxisEditField.Value = strcat(num2str(app.Yaxis(1)), ',', num2str(app.Yaxis(2)));
          
            end

        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            value = app.mEditField.Value;
            app.GridM = value;
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;
            app.GridN = value;
        end

        % Value changed function: CoefficientsEditField
        function CoefficientsEditFieldValueChanged(app, event)
            value = app.CoefficientsEditField.Value;
            
            % Get numbers from string array separated by comma
            app.RootsValueListBox.Items = {};
            app.Roots = 0;
            pol = str2double(strsplit(value, ','));

            % Error detection
            lengtherr = length(pol) > 7;
            polerr = ~(isnumeric(pol) && ~any(isnan(pol)) && ~any(isinf(pol)) && ~lengtherr && pol(1) ~= 0);
            errmsg = 'Input must be numbers separated with comma. Max length 7 with degree of 6. First coefficient must not be 0.';
            
            % Check that input value is number, not NaN or not Inf           
            if polerr
                errordlg(errmsg, 'Invalid input!');
                
            else
                app.Pol = pol;
                % Show the roots
                app.Roots = roots(app.Pol);
                rn = length(app.Roots);
                for i = 1: rn
                    app.RootsValueListBox.Items{1, i} = num2str(app.Roots(i));

                end

            end
        end

        % Value changed function: ToleranceEditField
        function ToleranceEditFieldValueChanged(app, event)
            value = app.ToleranceEditField.Value;
            app.Tol = value;
        end

        % Value changed function: BoundaryMEditField
        function BoundaryMEditFieldValueChanged(app, event)
            value = app.BoundaryMEditField.Value;
            app.M = value;
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.Depth = value;
        end

        % Value changed function: RootsDropDown
        function RootsDropDownValueChanged(app, event)
            value = app.RootsDropDown.Value;
            switch value
                case 'black'
                    app.RootColor = 'black';
                case 'yellow'
                    app.RootColor = 'y';
                case 'blue'
                    app.RootColor = 'b';
                case 'red'
                    app.RootColor = 'r';
                case 'green'
                    app.RootColor = 'g';

            end
        end

        % Callback function
        function RootsonButtonValueChanged(app, event)
            value = app.RootsonButton.Value;
            app.Rootson = value;
            if value              
                app.RootsonButton.Text = 'Roots off';
            else
                app.RootsonButton.Text = 'Roots on';
            end
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            if value
                grid(app.UIAxes, 'on');
                app.UIAxes.Layer = 'top';
            else
                grid(app.UIAxes, 'off');
            end
        end

        % Value changed function: AnimationCheckBox
        function AnimationCheckBoxValueChanged(app, event)
            value = app.AnimationCheckBox.Value;
            app.Animation = value;
        end

        % Value changed function: RootsCheckBox
        function RootsCheckBoxValueChanged(app, event)
            value = app.RootsCheckBox.Value;
            app.Rootson = value;
        end

        % Value changed function: Basin1DropDown
        function Basin1DropDownValueChanged(app, event)
            value = app.Basin1DropDown.Value;
            switch value
                case 'yellow'
                    app.Colormap(2, :) = [1, 1, 0];
                case 'magenta'
                    app.Colormap(2, :) = [1, 0, 1];
                case 'cyan'
                    app.Colormap(2, :) = [0, 1, 1];
                case 'red'
                    app.Colormap(2, :) = [1, 0, 0];
                case 'green'
                    app.Colormap(2, :) = [0, 1, 0];
                case 'blue'
                    app.Colormap(2, :) = [0, 0, 1];
                case 'white'
                    app.Colormap(2, :) = [1, 1, 1];
                case 'black'
                    app.Colormap(2, :) = [0, 0, 0];
            end
        end

        % Value changed function: Basin2DropDown
        function Basin2DropDownValueChanged(app, event)
            value = app.Basin2DropDown.Value;
            switch value
                case 'yellow'
                    app.Colormap(3, :) = [1, 1, 0];
                case 'magenta'
                    app.Colormap(3, :) = [1, 0, 1];
                case 'cyan'
                    app.Colormap(3, :) = [0, 1, 1];
                case 'red'
                    app.Colormap(3, :) = [1, 0, 0];
                case 'green'
                    app.Colormap(3, :) = [0, 1, 0];
                case 'blue'
                    app.Colormap(3, :) = [0, 0, 1];
                case 'white'
                    app.Colormap(3, :) = [1, 1, 1];
                case 'black'
                    app.Colormap(3, :) = [0, 0, 0];
            end
        end

        % Value changed function: Basin3DropDown
        function Basin3DropDownValueChanged(app, event)
            value = app.Basin3DropDown.Value;
            switch value
                case 'yellow'
                    app.Colormap(4, :) = [1, 1, 0];
                case 'magenta'
                    app.Colormap(4, :) = [1, 0, 1];
                case 'cyan'
                    app.Colormap(4, :) = [0, 1, 1];
                case 'red'
                    app.Colormap(4, :) = [1, 0, 0];
                case 'green'
                    app.Colormap(4, :) = [0, 1, 0];
                case 'blue'
                    app.Colormap(4, :) = [0, 0, 1];
                case 'white'
                    app.Colormap(4, :) = [1, 1, 1];
                case 'black'
                    app.Colormap(4, :) = [0, 0, 0];
            end
        end

        % Value changed function: Basin4DropDown
        function Basin4DropDownValueChanged(app, event)
            value = app.Basin4DropDown.Value;
            switch value
                case 'yellow'
                    app.Colormap(5, :) = [1, 1, 0];
                case 'magenta'
                    app.Colormap(5, :) = [1, 0, 1];
                case 'cyan'
                    app.Colormap(5, :) = [0, 1, 1];
                case 'red'
                    app.Colormap(5, :) = [1, 0, 0];
                case 'green'
                    app.Colormap(5, :) = [0, 1, 0];
                case 'blue'
                    app.Colormap(5, :) = [0, 0, 1];
                case 'white'
                    app.Colormap(5, :) = [1, 1, 1];
                case 'black'
                    app.Colormap(5, :) = [0, 0, 0];
            end
        end

        % Value changed function: Basin5DropDown
        function Basin5DropDownValueChanged(app, event)
            value = app.Basin5DropDown.Value;
            switch value
                case 'yellow'
                    app.Colormap(6, :) = [1, 1, 0];
                case 'magenta'
                    app.Colormap(6, :) = [1, 0, 1];
                case 'cyan'
                    app.Colormap(6, :) = [0, 1, 1];
                case 'red'
                    app.Colormap(6, :) = [1, 0, 0];
                case 'green'
                    app.Colormap(6, :) = [0, 1, 0];
                case 'blue'
                    app.Colormap(6, :) = [0, 0, 1];
                case 'white'
                    app.Colormap(6, :) = [1, 1, 1];
                case 'black'
                    app.Colormap(6, :) = [0, 0, 0];
            end
        end

        % Value changed function: RootsStyleKnob
        function RootsStyleKnobValueChanged(app, event)
            app.RootStyle = app.RootsStyleKnob.Value;           
        end

        % Value changed function: Basin6DropDown
        function Basin6DropDownValueChanged(app, event)
            value = app.Basin6DropDown.Value;
            switch value
                case 'yellow'
                    app.Colormap(7, :) = [1, 1, 0];
                case 'magenta'
                    app.Colormap(7, :) = [1, 0, 1];
                case 'cyan'
                    app.Colormap(7, :) = [0, 1, 1];
                case 'red'
                    app.Colormap(7, :) = [1, 0, 0];
                case 'green'
                    app.Colormap(7, :) = [0, 1, 0];
                case 'blue'
                    app.Colormap(7, :) = [0, 0, 1];
                case 'white'
                    app.Colormap(7, :) = [1, 1, 1];
                case 'black'
                    app.Colormap(7, :) = [0, 0, 0];
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Stop = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BasinsofAttractionUIFigure = uifigure;
            app.BasinsofAttractionUIFigure.Name = 'Basins of Attraction';
            app.UIAxes = uiaxes(app.BasinsofAttractionUIFigure);
            xlabel(app.UIAxes, 'Real')
            ylabel(app.UIAxes, 'Imaginary')
            app.PlotButton = uibutton(app.BasinsofAttractionUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GridPanel = uipanel(app.BasinsofAttractionUIFigure);
            app.GridPanel.Title = 'Grid';
            app.mEditFieldLabel = uilabel(app.GridPanel);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.GridPanel, 'numeric');
            app.mEditField.Limits = [100 10000];
            app.mEditField.RoundFractionalValues = 'on';
            app.mEditField.ValueDisplayFormat = '%.0f';
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Value = 100;
            app.nEditFieldLabel = uilabel(app.GridPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.GridPanel, 'numeric');
            app.nEditField.Limits = [100 10000];
            app.nEditField.RoundFractionalValues = 'on';
            app.nEditField.ValueDisplayFormat = '%.0f';
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Value = 100;
            app.XaxisEditFieldLabel = uilabel(app.GridPanel);
            app.XaxisEditFieldLabel.Text = 'X-axis';
            app.XaxisEditField = uieditfield(app.GridPanel, 'text');
            app.XaxisEditField.ValueChangedFcn = createCallbackFcn(app, @XaxisEditFieldValueChanged, true);
            app.YaxisEditFieldLabel = uilabel(app.GridPanel);
            app.YaxisEditFieldLabel.Text = 'Y-axis';
            app.YaxisEditField = uieditfield(app.GridPanel, 'text');
            app.YaxisEditField.ValueChangedFcn = createCallbackFcn(app, @YaxisEditFieldValueChanged, true);
            app.GridCheckBox = uicheckbox(app.GridPanel);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.ToleranceEditFieldLabel = uilabel(app.BasinsofAttractionUIFigure);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.BasinsofAttractionUIFigure, 'numeric');
            app.ToleranceEditField.Limits = [1e-05 Inf];
            app.ToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceEditFieldValueChanged, true);
            app.ToleranceEditField.Value = 1e-05;
            app.BoundaryMEditFieldLabel = uilabel(app.BasinsofAttractionUIFigure);
            app.BoundaryMEditFieldLabel.Text = 'BoundaryM';
            app.BoundaryMEditField = uieditfield(app.BasinsofAttractionUIFigure, 'numeric');
            app.BoundaryMEditField.Limits = [1 Inf];
            app.BoundaryMEditField.ValueChangedFcn = createCallbackFcn(app, @BoundaryMEditFieldValueChanged, true);
            app.BoundaryMEditField.Value = 1000;
            app.CoefficientsEditFieldLabel = uilabel(app.BasinsofAttractionUIFigure);
            app.CoefficientsEditFieldLabel.Text = 'Coefficients';
            app.CoefficientsEditField = uieditfield(app.BasinsofAttractionUIFigure, 'text');
            app.CoefficientsEditField.ValueChangedFcn = createCallbackFcn(app, @CoefficientsEditFieldValueChanged, true);
            app.CoefficientsEditField.Value = '1,0,0,-1';
            app.IterationsEditFieldLabel = uilabel(app.BasinsofAttractionUIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.BasinsofAttractionUIFigure, 'numeric');
            app.IterationsEditField.Limits = [5 Inf];
            app.IterationsEditField.ValueDisplayFormat = '%.0f';
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Value = 5;
            app.RootsValueLabel = uilabel(app.BasinsofAttractionUIFigure);
            app.RootsValueLabel.Text = 'Roots Value';
            app.RootsValueListBox = uilistbox(app.BasinsofAttractionUIFigure);
            app.RootsValueListBox.Items = {};
            app.RootsValueListBox.Value = {};
            app.ColorPanel = uipanel(app.BasinsofAttractionUIFigure);
            app.ColorPanel.Title = 'Color';
            app.RootsDropDownLabel = uilabel(app.ColorPanel);
            app.RootsDropDownLabel.Text = 'Roots';
            app.RootsDropDown = uidropdown(app.ColorPanel);
            app.RootsDropDown.Items = {'Option 1'};
            app.RootsDropDown.ValueChangedFcn = createCallbackFcn(app, @RootsDropDownValueChanged, true);
            app.Basin1DropDownLabel = uilabel(app.ColorPanel);
            app.Basin1DropDownLabel.Text = 'Basin1';
            app.Basin1DropDown = uidropdown(app.ColorPanel);
            app.Basin1DropDown.Items = {'blue', 'red', 'green', 'yellow', 'cyan', 'magenta', 'white', 'black'};
            app.Basin1DropDown.ValueChangedFcn = createCallbackFcn(app, @Basin1DropDownValueChanged, true);
            app.Basin1DropDown.Value = 'red';
            app.Basin2DropDownLabel = uilabel(app.ColorPanel);
            app.Basin2DropDownLabel.Text = 'Basin2';
            app.Basin2DropDown = uidropdown(app.ColorPanel);
            app.Basin2DropDown.Items = {'blue', 'red', 'green', 'yellow', 'cyan', 'magenta', 'white', 'black'};
            app.Basin2DropDown.ValueChangedFcn = createCallbackFcn(app, @Basin2DropDownValueChanged, true);
            app.Basin2DropDown.Value = 'blue';
            app.Basin3DropDownLabel = uilabel(app.ColorPanel);
            app.Basin3DropDownLabel.Text = 'Basin3';
            app.Basin3DropDown = uidropdown(app.ColorPanel);
            app.Basin3DropDown.Items = {'blue', 'red', 'green', 'yellow', 'cyan', 'magenta', 'white', 'black'};
            app.Basin3DropDown.ValueChangedFcn = createCallbackFcn(app, @Basin3DropDownValueChanged, true);
            app.Basin3DropDown.Value = 'green';
            app.Basin4DropDownLabel = uilabel(app.ColorPanel);
            app.Basin4DropDownLabel.Text = 'Basin4';
            app.Basin4DropDown = uidropdown(app.ColorPanel);
            app.Basin4DropDown.Items = {'blue', 'red', 'green', 'yellow', 'cyan', 'magenta', 'black'};
            app.Basin4DropDown.ValueChangedFcn = createCallbackFcn(app, @Basin4DropDownValueChanged, true);
            app.Basin4DropDown.Value = 'yellow';
            app.Basin5DropDownLabel = uilabel(app.ColorPanel);
            app.Basin5DropDownLabel.Text = 'Basin5';
            app.Basin5DropDown = uidropdown(app.ColorPanel);
            app.Basin5DropDown.Items = {'blue', 'red', 'green', 'yellow', 'cyan', 'magenta', 'white', 'black'};
            app.Basin5DropDown.ValueChangedFcn = createCallbackFcn(app, @Basin5DropDownValueChanged, true);
            app.Basin5DropDown.Value = 'cyan';
            app.Basin6DropDownLabel = uilabel(app.ColorPanel);
            app.Basin6DropDownLabel.Text = 'Basin6';
            app.Basin6DropDown = uidropdown(app.ColorPanel);
            app.Basin6DropDown.Items = {'blue', 'red', 'green', 'yellow', 'cyan', 'magenta', 'white', 'black'};
            app.Basin6DropDown.ValueChangedFcn = createCallbackFcn(app, @Basin6DropDownValueChanged, true);
            app.Basin6DropDown.Value = 'magenta';
            app.AnimationCheckBox = uicheckbox(app.BasinsofAttractionUIFigure);
            app.AnimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnimationCheckBoxValueChanged, true);
            app.AnimationCheckBox.Text = 'Animation';
            app.RootsCheckBox = uicheckbox(app.BasinsofAttractionUIFigure);
            app.RootsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RootsCheckBoxValueChanged, true);
            app.RootsCheckBox.Text = 'Roots';
            app.RootsStyleKnobLabel = uilabel(app.BasinsofAttractionUIFigure);
            app.RootsStyleKnobLabel.Text = 'RootsStyle';
            app.RootsStyleKnob = uiknob(app.BasinsofAttractionUIFigure, 'discrete');
            app.RootsStyleKnob.Items = {'o', 'x', '*', '+'};
            app.RootsStyleKnob.ItemsData = {'o', 'x', '*', '+'};
            app.RootsStyleKnob.ValueChangedFcn = createCallbackFcn(app, @RootsStyleKnobValueChanged, true);
            app.RootsStyleKnob.Value = 'o';
            app.ElapsedTimeEditFieldLabel = uilabel(app.BasinsofAttractionUIFigure);
            app.ElapsedTimeEditFieldLabel.Text = 'Elapsed Time';
            app.ElapsedTimeEditField = uieditfield(app.BasinsofAttractionUIFigure, 'numeric');
            app.ElapsedTimeEditField.Editable = 'off';
            app.CurrentIterationLabel = uilabel(app.BasinsofAttractionUIFigure);
            app.CurrentIterationLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.BasinsofAttractionUIFigure, 'numeric');
            app.CurrentIterationEditField.Editable = 'off';
            app.StopButton = uibutton(app.BasinsofAttractionUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BoA4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BasinsofAttractionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BasinsofAttractionUIFigure)
        end
    end
end