classdef BoA_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BasinsofAttractionUIFigure  matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        PlotButton                  matlab.ui.control.Button
        GridPanel                   matlab.ui.container.Panel
        mEditFieldLabel             matlab.ui.control.Label
        mEditField                  matlab.ui.control.NumericEditField
        nEditFieldLabel             matlab.ui.control.Label
        nEditField                  matlab.ui.control.NumericEditField
        XaxisEditFieldLabel         matlab.ui.control.Label
        XaxisEditField              matlab.ui.control.EditField
        YaxisEditFieldLabel         matlab.ui.control.Label
        YaxisEditField              matlab.ui.control.EditField
        GridonButton                matlab.ui.control.StateButton
        ToleranceEditFieldLabel     matlab.ui.control.Label
        ToleranceEditField          matlab.ui.control.NumericEditField
        MEditFieldLabel             matlab.ui.control.Label
        MEditField                  matlab.ui.control.NumericEditField
        CoefficientsEditFieldLabel  matlab.ui.control.Label
        CoefficientsEditField       matlab.ui.control.EditField
        IterationsEditFieldLabel    matlab.ui.control.Label
        IterationsEditField         matlab.ui.control.NumericEditField
        RootcolorDropDownLabel      matlab.ui.control.Label
        RootcolorDropDown           matlab.ui.control.DropDown
        RootsonButton               matlab.ui.control.StateButton
        AnimationonButton           matlab.ui.control.StateButton
    end


    properties (Access = private)
        Xaxis(1, 2) = [-1.5 1.5]        %Limits of the x-axis
        Yaxis(1, 2) = [-1.5 1.5]        %Limits of the y-axis
        GridM = 500;                    %Number of grid points m
        GridN = 500;                    %Number of grid points n
        Tol = 1e-1;                     %Tolerance for convergence
        M = 1000;                       %Bound for divergence
        Pol;                            %Polynomial
        Depth = 30;                     %Iterations of Newton method
        RootColor = 'black';            %Roots coloring
        Rootson = 0;                    %Roots ploting on/off
        Colormap;                       %Colormap for plotting
        Animation = 0;                  %Animation on/off
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Write the default values to text boxes
            app.XaxisEditField.Value = strcat(num2str(app.Xaxis(1)), ',', num2str(app.Xaxis(2)));
            app.YaxisEditField.Value = strcat(num2str(app.Yaxis(1)), ',', num2str(app.Yaxis(2)));
            app.mEditField.Value = app.GridM;
            app.nEditField.Value = app.GridN;
            app.ToleranceEditField.Value = app.Tol;
            app.MEditField.Value = app.M;
            app.IterationsEditField.Value = app.Depth;
            %Set the axis limits
            app.UIAxes.XLim = app.Xaxis;
            app.UIAxes.YLim = app.Yaxis;
            
            app.RootcolorDropDown.Items = {'Black', 'Yellow', 'Blue', 'Red', 'Green'};
           
        end

        % Value changed function: XaxisEditField
        function XaxisEditFieldValueChanged(app, event)
            value = app.XaxisEditField.Value;
            xval = str2double(strsplit(value, ','));
            oldx = app.Xaxis; % Stores the old value in case of error
            % Check correctness of the input
            xerr = ~(isnumeric(xval) && ~any(isnan(xval)) && ~any(isinf(xval)) && isreal(xval));
            errmsg = 'Input must be two real numbers separated with a comma.';
            
            % Check that input value is real number, not NaN, not Inf and array length is correct
            % Error dialog window in case of error
            try
                
                if xerr
                    error(' ');
                else
                     app.Xaxis = xval;
                    
                    %Set the axis limits
                    app.UIAxes.XLim = app.Xaxis; 
                end
                
            catch
                
                errordlg(errmsg, 'Invalid input!');
                % Assign the old value and write it to the text box
                app.Xaxis = oldx;
                app.XaxisEditField.Value = strcat(num2str(app.Xaxis(1)), ',', num2str(app.Xaxis(2)));
          
            end
            
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            np = length(app.Pol) - 1;
            dp = polyder(app.Pol);
            r = roots(app.Pol);
            x = linspace(app.Xaxis(1), app.Xaxis(2), app.GridM);
            y = linspace(app.Yaxis(1), app.Yaxis(2), app.GridN);
            C = zeros(length(y), length(x), 'uint8');
            
            [X, Y] = meshgrid(x, y);
            Z = X + 1i*Y;

            %tic

            for j = 1:app.Depth
                Z = Z - polyval(app.Pol, Z)./(polyval(dp, Z) + eps); % To avoid division by zero
                for k = 1:np
                    C(abs(Z - r(k)) < app.Tol) = k;
                end
                C(abs(Z) > app.M) = np + 1;
                if app.Animation
                    image(app.UIAxes, x, y, C);
                    colormap(app.UIAxes, app.Colormap);
                    grid(app.UIAxes, 'on');
                    drawnow
                end
            end
            %toc
            image(app.UIAxes, x, y, C);
            colormap(app.UIAxes, app.Colormap);
            grid(app.UIAxes, 'on');
            app.UIAxes.XGrid = 'on';
            if app.Rootson
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, r, 'o', 'MarkerFaceColor', app.RootColor, 'MarkerEdgeColor', 'black')
                app.UIAxes.XGrid = 'on';
                hold(app.UIAxes, 'off');
            end
            get(app.UIAxes)
        end

        % Value changed function: YaxisEditField
        function YaxisEditFieldValueChanged(app, event)
            value = app.YaxisEditField.Value;
            %app.Yaxis = str2double(strsplit(value, ','));
            yval = str2double(strsplit(value, ','));
            oldy = app.Xaxis; % Stores the old value in case of error
            % Check correctness of the input
            yerr = ~(isnumeric(yval) && ~any(isnan(yval)) && ~any(isinf(yval)) && isreal(yval));
            errmsg = 'Input must be two real numbers separated with a comma.';
            
            % Check that input value is real number, not NaN, not Inf and array length is correct
            % Error dialog window in case of error
            try
                
                if yerr
                    error(' ');
                    
                else
                    app.Yaxis = yval;
                    
                    %Set the axis limits
                    app.UIAxes.YLim = app.Yaxis;  
                end
                
            catch
                
                errordlg(errmsg, 'Invalid input!');
                % Assign the old value and write it to the text box
                app.Yaxis = oldy;
                app.YaxisEditField.Value = strcat(num2str(app.Yaxis(1)), ',', num2str(app.Yaxis(2)));
          
            end

        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            value = app.mEditField.Value;
            app.GridM = value;
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;
            app.GridN = value;
        end

        % Value changed function: CoefficientsEditField
        function CoefficientsEditFieldValueChanged(app, event)
            value = app.CoefficientsEditField.Value;
            
            % Get numbers from string array separated by ,
            pol = str2double(strsplit(value, ','));
            len = length(pol);
            
            % Colormap
            colors = [1 1 1; 1 0 0; 0 1 0; 0 0 1; 1 1 0; 1 0 1; 0 1 1];
            
            
            % Error detection
            lengtherr = length(pol) > 6;
            polerr = ~(isnumeric(pol) && ~any(isnan(pol)) && ~any(isinf(pol)) && ~lengtherr);
            errmsg = 'Input must be numbers separated with comma. Max length 6.';
            
            % Check that input value is number, not NaN or not Inf           
            if polerr
                errordlg(errmsg, 'Invalid input!');
                
            else
                app.Pol = pol;
                % Colormap handling
                cmap = colors(1:len, :);
                cmap(len + 1,:) = [0 0 0];
                app.Colormap = cmap;
            end
        end

        % Value changed function: ToleranceEditField
        function ToleranceEditFieldValueChanged(app, event)
            value = app.ToleranceEditField.Value;
            app.Tol = value;
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            value = app.MEditField.Value;
            app.M = value;
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.Depth = value;
        end

        % Value changed function: RootcolorDropDown
        function RootcolorDropDownValueChanged(app, event)
            value = app.RootcolorDropDown.Value;
            switch value
                case 'Black'
                    app.RootColor = 'black';
                case 'Yellow'
                    app.RootColor = 'y';
                case 'Blue'
                    app.RootColor = 'b';
                case 'Red'
                    app.RootColor = 'r';
                case 'Green'
                    app.RootColor = 'g';

            end
        end

        % Value changed function: GridonButton
        function GridonButtonValueChanged(app, event)
            value = app.GridonButton.Value;
            %app.UIAxes.GridAlphaMode = 'manual';
            %app.UIAxes.GridAlpha = 1;
            if value
                grid(app.UIAxes, 'on');
                app.GridonButton.Text = 'Grid off';
            else
                grid(app.UIAxes, 'off');
                app.GridonButton.Text = 'Grid on';
            end
        end

        % Value changed function: RootsonButton
        function RootsonButtonValueChanged(app, event)
            value = app.RootsonButton.Value;
            app.Rootson = value;
            if value              
               app.RootsonButton.Text = 'Roots off';
            else
                app.RootsonButton.Text = 'Roots on';
            end
        end

        % Value changed function: AnimationonButton
        function AnimationonButtonValueChanged(app, event)
            value = app.AnimationonButton.Value;
            app.Animation = value;
            if value
                app.AnimationonButton.Text = 'Animation off';
            else
                app.AnimationonButton.Text = 'Animation on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BasinsofAttractionUIFigure = uifigure;
            app.BasinsofAttractionUIFigure.Name = 'Basins of Attraction';
            app.UIAxes = uiaxes(app.BasinsofAttractionUIFigure);
            xlabel(app.UIAxes, 'Real')
            ylabel(app.UIAxes, 'Imaginary')
            app.PlotButton = uibutton(app.BasinsofAttractionUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GridPanel = uipanel(app.BasinsofAttractionUIFigure);
            app.GridPanel.Title = 'Grid';
            app.mEditFieldLabel = uilabel(app.GridPanel);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.GridPanel, 'numeric');
            app.mEditField.Limits = [100 10000];
            app.mEditField.RoundFractionalValues = 'on';
            app.mEditField.ValueDisplayFormat = '%.0f';
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Value = 100;
            app.nEditFieldLabel = uilabel(app.GridPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.GridPanel, 'numeric');
            app.nEditField.Limits = [100 10000];
            app.nEditField.RoundFractionalValues = 'on';
            app.nEditField.ValueDisplayFormat = '%.0f';
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Value = 100;
            app.XaxisEditFieldLabel = uilabel(app.GridPanel);
            app.XaxisEditFieldLabel.Text = 'X-axis';
            app.XaxisEditField = uieditfield(app.GridPanel, 'text');
            app.XaxisEditField.ValueChangedFcn = createCallbackFcn(app, @XaxisEditFieldValueChanged, true);
            app.YaxisEditFieldLabel = uilabel(app.GridPanel);
            app.YaxisEditFieldLabel.Text = 'Y-axis';
            app.YaxisEditField = uieditfield(app.GridPanel, 'text');
            app.YaxisEditField.ValueChangedFcn = createCallbackFcn(app, @YaxisEditFieldValueChanged, true);
            app.GridonButton = uibutton(app.GridPanel, 'state');
            app.GridonButton.ValueChangedFcn = createCallbackFcn(app, @GridonButtonValueChanged, true);
            app.GridonButton.Text = 'Grid on';
            app.ToleranceEditFieldLabel = uilabel(app.BasinsofAttractionUIFigure);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.BasinsofAttractionUIFigure, 'numeric');
            app.ToleranceEditField.Limits = [1e-05 Inf];
            app.ToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceEditFieldValueChanged, true);
            app.ToleranceEditField.Value = 1e-05;
            app.MEditFieldLabel = uilabel(app.BasinsofAttractionUIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.BasinsofAttractionUIFigure, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.CoefficientsEditFieldLabel = uilabel(app.BasinsofAttractionUIFigure);
            app.CoefficientsEditFieldLabel.Text = 'Coefficients';
            app.CoefficientsEditField = uieditfield(app.BasinsofAttractionUIFigure, 'text');
            app.CoefficientsEditField.ValueChangedFcn = createCallbackFcn(app, @CoefficientsEditFieldValueChanged, true);
            app.IterationsEditFieldLabel = uilabel(app.BasinsofAttractionUIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.BasinsofAttractionUIFigure, 'numeric');
            app.IterationsEditField.Limits = [5 Inf];
            app.IterationsEditField.ValueDisplayFormat = '%.0f';
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Value = 5;
            app.RootcolorDropDownLabel = uilabel(app.BasinsofAttractionUIFigure);
            app.RootcolorDropDownLabel.Text = 'Root color';
            app.RootcolorDropDown = uidropdown(app.BasinsofAttractionUIFigure);
            app.RootcolorDropDown.ValueChangedFcn = createCallbackFcn(app, @RootcolorDropDownValueChanged, true);
            app.RootsonButton = uibutton(app.BasinsofAttractionUIFigure, 'state');
            app.RootsonButton.ValueChangedFcn = createCallbackFcn(app, @RootsonButtonValueChanged, true);
            app.RootsonButton.Text = 'Roots on';
            app.AnimationonButton = uibutton(app.BasinsofAttractionUIFigure, 'state');
            app.AnimationonButton.ValueChangedFcn = createCallbackFcn(app, @AnimationonButtonValueChanged, true);
            app.AnimationonButton.Text = 'Animation on';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BoA_0

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BasinsofAttractionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BasinsofAttractionUIFigure)
        end
    end
end