classdef PlotBasinAttraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        XminEditFieldLabel           matlab.ui.control.Label
        XminEditField                matlab.ui.control.NumericEditField
        XmaxEditFieldLabel           matlab.ui.control.Label
        XmaxEditField                matlab.ui.control.NumericEditField
        PlotButton                   matlab.ui.control.Button
        Basin1ColorDropDownLabel     matlab.ui.control.Label
        Basin1ColorDropDown          matlab.ui.control.DropDown
        RootsStyleKnobLabel          matlab.ui.control.Label
        RootsStyleKnob               matlab.ui.control.DiscreteKnob
        Basin2ColorDropDownLabel     matlab.ui.control.Label
        Basin2ColorDropDown          matlab.ui.control.DropDown
        Basin3ColorDropDownLabel     matlab.ui.control.Label
        Basin3ColorDropDown          matlab.ui.control.DropDown
        Basin4ColorDropDownLabel     matlab.ui.control.Label
        Basin4ColorDropDown          matlab.ui.control.DropDown
        RootsColorDropDownLabel      matlab.ui.control.Label
        RootsColorDropDown           matlab.ui.control.DropDown
        XnumberEditFieldLabel        matlab.ui.control.Label
        XnumberEditField             matlab.ui.control.NumericEditField
        YminEditFieldLabel           matlab.ui.control.Label
        YminEditField                matlab.ui.control.NumericEditField
        YmaxEditFieldLabel           matlab.ui.control.Label
        YmaxEditField                matlab.ui.control.NumericEditField
        YnumberEditFieldLabel        matlab.ui.control.Label
        YnumberEditField             matlab.ui.control.NumericEditField
        DivergeBoundEditFieldLabel   matlab.ui.control.Label
        DivergeBoundEditField        matlab.ui.control.NumericEditField
        ConvergeToleranceEditFieldLabel  matlab.ui.control.Label
        ConvergeToleranceEditField   matlab.ui.control.NumericEditField
        GridCheckBox                 matlab.ui.control.CheckBox
        IterationsEditFieldLabel     matlab.ui.control.Label
        IterationsEditField          matlab.ui.control.NumericEditField
        fcoefficientsEditFieldLabel  matlab.ui.control.Label
        fcoefficientsEditField       matlab.ui.control.EditField
        RootsValueListBoxLabel       matlab.ui.control.Label
        RootsValueListBox            matlab.ui.control.ListBox
        Basin5ColorDropDownLabel     matlab.ui.control.Label
        Basin5ColorDropDown          matlab.ui.control.DropDown
        Basin6ColorDropDownLabel     matlab.ui.control.Label
        Basin6ColorDropDown          matlab.ui.control.DropDown
        Basin7ColorDropDownLabel     matlab.ui.control.Label
        Basin7ColorDropDown          matlab.ui.control.DropDown
        ElapsedtimeEditFieldLabel    matlab.ui.control.Label
        ElapsedtimeEditField         matlab.ui.control.NumericEditField
        fDerivationEditFieldLabel    matlab.ui.control.Label
        fDerivationEditField         matlab.ui.control.EditField
        MarkerSizeSliderLabel        matlab.ui.control.Label
        MarkerSizeSlider             matlab.ui.control.Slider
    end


    properties (Access = private)
        
        Plot = '';   % Handle to plot
        Fz;
        Dfz;
        Roots;
        Iter;
        Cmap(7,3);
        Time;
    end

    methods (Access = private)
    
        function updatePlot(app)
            xmin = app.XminEditField.Value;
            xmax = app.XmaxEditField.Value;
            ymin = app.YminEditField.Value;
            ymax = app.YmaxEditField.Value;
            rn = length(app.Roots);
            app.Iter = app.IterationsEditField.Value;
            if xmin >= xmax
                uialert(app.UIFigure, 'Xmin must be less than Xmax', 'Error');
            elseif ymin >= ymax
                uialert(app.UIFigure, 'Ymin must be less than Ymax', 'Error');
            elseif length(app.Fz) > 8
                uialert(app.UIFigure, 'function degree must be less than 8', 'Error');
            else
                M = app.DivergeBoundEditField.Value;
                m = app.XnumberEditField.Value;
                n = app.YnumberEditField.Value;
                x = linspace(xmin, xmax, m);
                y = linspace(ymin, ymax, n);
                C = zeros(length(x), length(y), 'uint8');                                    
                [X, Y] = meshgrid(x, y);
                Z = X + 1i*Y;
                depth = app.Iter;
                tol = app.ConvergeToleranceEditField.Value;
                tic
                for i = 1:depth
                    Z = Z - polyval(app.Fz, Z)./(polyval(app.Dfz, Z) + eps); % To avoid division by zero
                    for k = 1:rn
                        C(abs(Z - app.Roots(k)) < tol) = k;
                    end
                    C(abs(Z) > M) = rn + 1;
                end
                app.Time = toc;
                app.ElapsedtimeEditField.Value = app.Time;
                image(app.UIAxes, x, y, C)
                colormap(app.UIAxes, app.Cmap);
                hold on
                app.Plot = plot(app.UIAxes, app.Roots)
                xlim(app.UIAxes, [xmin xmax])
                ylim(app.UIAxes, [ymin ymax])
                
            end
        end
        
    end


    methods (Access = private)

        % Callback function
        function fCoefficientsEditFieldValueChanged(app, event)
            try
                updatePlot(app);
            catch me
                uialert(app.UIFigure, me.message, 'Error');
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            updatePlot(app)
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            if value
                grid(app.UIAxes, 'on');
            else
                grid(app.UIAxes, 'off');
            end
        end

        % Value changed function: Basin1ColorDropDown
        function Basin1ColorDropDownValueChanged(app, event)
            value = app.Basin1ColorDropDown.Value;
            switch value
                case 'yellow'
                    value = [1, 1, 0];
                case 'magenta'
                    value = [1, 0, 1];
                case 'cyan'
                    value = [0, 1, 1];
                case 'red'
                    value = [1, 0, 0];
                case 'green'
                    value = [0, 1, 0];
                case 'blue'
                    value = [0, 0, 1];
                case 'white'
                    value = [1, 1, 1];
                case 'black'
                    value = [0, 0, 0];
            end
            app.Cmap(1, :) = value;        
        end

        % Value changed function: Basin2ColorDropDown
        function Basin2ColorDropDownValueChanged(app, event)
            value = app.Basin2ColorDropDown.Value;
            switch value
                case 'yellow'
                    value = [1, 1, 0];
                case 'magenta'
                    value = [1, 0, 1];
                case 'cyan'
                    value = [0, 1, 1];
                case 'red'
                    value = [1, 0, 0];
                case 'green'
                    value = [0, 1, 0];
                case 'blue'
                    value = [0, 0, 1];
                case 'white'
                    value = [1, 1, 1];
                case 'black'
                    value = [0, 0, 0];
            end
            app.Cmap(2, :) = value;
        end

        % Value changed function: Basin3ColorDropDown
        function Basin3ColorDropDownValueChanged(app, event)
            value = app.Basin3ColorDropDown.Value;
            switch value
                case 'yellow'
                    value = [1, 1, 0];
                case 'magenta'
                    value = [1, 0, 1];
                case 'cyan'
                    value = [0, 1, 1];
                case 'red'
                    value = [1, 0, 0];
                case 'green'
                    value = [0, 1, 0];
                case 'blue'
                    value = [0, 0, 1];
                case 'white'
                    value = [1, 1, 1];
                case 'black'
                    value = [0, 0, 0];
            end
            app.Cmap(3, :) = value;
        end

        % Value changed function: Basin4ColorDropDown
        function Basin4ColorDropDownValueChanged(app, event)
            value = app.Basin4ColorDropDown.Value;
            switch value
                case 'yellow'
                    value = [1, 1, 0];
                case 'magenta'
                    value = [1, 0, 1];
                case 'cyan'
                    value = [0, 1, 1];
                case 'red'
                    value = [1, 0, 0];
                case 'green'
                    value = [0, 1, 0];
                case 'blue'
                    value = [0, 0, 1];
                case 'white'
                    value = [1, 1, 1];
                case 'black'
                    value = [0, 0, 0];
            end
            app.Cmap(4, :) = value;
        end

        % Value changed function: Basin5ColorDropDown
        function Basin5ColorDropDownValueChanged(app, event)
            value = app.Basin5ColorDropDown.Value;
            switch value
                case 'yellow'
                    value = [1, 1, 0];
                case 'magenta'
                    value = [1, 0, 1];
                case 'cyan'
                    value = [0, 1, 1];
                case 'red'
                    value = [1, 0, 0];
                case 'green'
                    value = [0, 1, 0];
                case 'blue'
                    value = [0, 0, 1];
                case 'white'
                    value = [1, 1, 1];
                case 'black'
                    value = [0, 0, 0];
            end
            app.Cmap(5, :) = value;
        end

        % Value changed function: Basin6ColorDropDown
        function Basin6ColorDropDownValueChanged(app, event)
            value = app.Basin6ColorDropDown.Value;
            switch value
                case 'yellow'
                    value = [1, 1, 0];
                case 'magenta'
                    value = [1, 0, 1];
                case 'cyan'
                    value = [0, 1, 1];
                case 'red'
                    value = [1, 0, 0];
                case 'green'
                    value = [0, 1, 0];
                case 'blue'
                    value = [0, 0, 1];
                case 'white'
                    value = [1, 1, 1];
                case 'black'
                    value = [0, 0, 0];
            end
            app.Cmap(6, :) = value;
        end

        % Value changed function: Basin7ColorDropDown
        function Basin7ColorDropDownValueChanged(app, event)
            value = app.Basin7ColorDropDown.Value;
            switch value
                case 'yellow'
                    value = [1, 1, 0];
                case 'magenta'
                    value = [1, 0, 1];
                case 'cyan'
                    value = [0, 1, 1];
                case 'red'
                    value = [1, 0, 0];
                case 'green'
                    value = [0, 1, 0];
                case 'blue'
                    value = [0, 0, 1];
                case 'white'
                    value = [1, 1, 1];
                case 'black'
                    value = [0, 0, 0];
            end
            app.Cmap(7, :) = value;
        end

        % Value changed function: fcoefficientsEditField
        function fcoefficientsEditFieldValueChanged(app, event)
            value = app.fcoefficientsEditField.Value;
            app.Fz = str2num(value);
            app.Roots = roots(app.Fz);
            rn = length(app.Roots);
            for i = 1: rn
                app.RootsValueListBox.Items{1, i} = num2str(app.Roots(i));
            end
            app.Plot = plot(app.UIAxes, app.Roots, 'o', 'MarkerFaceColor', 'k')
            app.Dfz = polyder(app.Fz);
            app.fDerivationEditField.Value = num2str(app.Dfz); 
            hold on
        end

        % Value changed function: RootsStyleKnob
        function RootsStyleKnobValueChanged(app, event)
            app.Plot.Marker = app.RootsStyleKnob.Value;
            
        end

        % Value changed function: RootsColorDropDown
        function RootsColorDropDownValueChanged(app, event)
            app.Plot.MarkerFaceColor = app.RootsColorDropDown.Value;
            
        end

        % Value changed function: MarkerSizeSlider
        function MarkerSizeSliderValueChanged(app, event)
            app.Plot.Markersize = app.MarkerSizeSlider.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-Inf Inf];
            app.UIAxes.YLim = [-Inf Inf];
            app.UIAxes.ZLim = [-Inf Inf];
            app.UIAxes.CLim = [-Inf Inf];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.XminEditFieldLabel = uilabel(app.UIFigure);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Basin1ColorDropDownLabel = uilabel(app.UIFigure);
            app.Basin1ColorDropDownLabel.Text = 'Basin1Color';
            app.Basin1ColorDropDown = uidropdown(app.UIFigure);
            app.Basin1ColorDropDown.Items = {'black', 'blue', 'red', 'green', 'yellow', 'magenta', 'cyan'};
            app.Basin1ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @Basin1ColorDropDownValueChanged, true);
            app.Basin1ColorDropDown.Value = 'black';
            app.RootsStyleKnobLabel = uilabel(app.UIFigure);
            app.RootsStyleKnobLabel.Text = 'RootsStyle';
            app.RootsStyleKnob = uiknob(app.UIFigure, 'discrete');
            app.RootsStyleKnob.Items = {'o', 'x', '*', '+'};
            app.RootsStyleKnob.ItemsData = {'o', 'x', '*', '+'};
            app.RootsStyleKnob.ValueChangedFcn = createCallbackFcn(app, @RootsStyleKnobValueChanged, true);
            app.RootsStyleKnob.Value = 'o';
            app.Basin2ColorDropDownLabel = uilabel(app.UIFigure);
            app.Basin2ColorDropDownLabel.Text = 'Basin2Color';
            app.Basin2ColorDropDown = uidropdown(app.UIFigure);
            app.Basin2ColorDropDown.Items = {'black', 'blue', 'red', 'green', 'yellow', 'magenta', 'cyan'};
            app.Basin2ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @Basin2ColorDropDownValueChanged, true);
            app.Basin2ColorDropDown.Value = 'black';
            app.Basin3ColorDropDownLabel = uilabel(app.UIFigure);
            app.Basin3ColorDropDownLabel.Text = 'Basin3Color';
            app.Basin3ColorDropDown = uidropdown(app.UIFigure);
            app.Basin3ColorDropDown.Items = {'black', 'blue', 'red', 'green', 'yellow', 'magenta', 'cyan'};
            app.Basin3ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @Basin3ColorDropDownValueChanged, true);
            app.Basin3ColorDropDown.Value = 'black';
            app.Basin4ColorDropDownLabel = uilabel(app.UIFigure);
            app.Basin4ColorDropDownLabel.Text = 'Basin4Color';
            app.Basin4ColorDropDown = uidropdown(app.UIFigure);
            app.Basin4ColorDropDown.Items = {'black', 'blue', 'red', 'green', 'yellow', 'magenta', 'cyan'};
            app.Basin4ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @Basin4ColorDropDownValueChanged, true);
            app.Basin4ColorDropDown.Value = 'black';
            app.RootsColorDropDownLabel = uilabel(app.UIFigure);
            app.RootsColorDropDownLabel.Text = 'RootsColor';
            app.RootsColorDropDown = uidropdown(app.UIFigure);
            app.RootsColorDropDown.Items = {'black', 'blue', 'red', 'green', 'yellow', 'magenta', 'cyan'};
            app.RootsColorDropDown.ValueChangedFcn = createCallbackFcn(app, @RootsColorDropDownValueChanged, true);
            app.RootsColorDropDown.Value = 'black';
            app.XnumberEditFieldLabel = uilabel(app.UIFigure);
            app.XnumberEditFieldLabel.Text = 'Xnumber';
            app.XnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.XnumberEditField.Limits = [1 Inf];
            app.XnumberEditField.ValueDisplayFormat = '%.0f';
            app.XnumberEditField.Value = 6;
            app.YminEditFieldLabel = uilabel(app.UIFigure);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditFieldLabel = uilabel(app.UIFigure);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YnumberEditFieldLabel = uilabel(app.UIFigure);
            app.YnumberEditFieldLabel.Text = 'Ynumber';
            app.YnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.YnumberEditField.Limits = [0 Inf];
            app.YnumberEditField.ValueDisplayFormat = '%.0f';
            app.YnumberEditField.Value = 6;
            app.DivergeBoundEditFieldLabel = uilabel(app.UIFigure);
            app.DivergeBoundEditFieldLabel.Text = 'DivergeBound';
            app.DivergeBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.DivergeBoundEditField.ValueDisplayFormat = '%.0f';
            app.ConvergeToleranceEditFieldLabel = uilabel(app.UIFigure);
            app.ConvergeToleranceEditFieldLabel.Text = 'ConvergeTolerance';
            app.ConvergeToleranceEditField = uieditfield(app.UIFigure, 'numeric');
            app.GridCheckBox = uicheckbox(app.UIFigure);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.IterationsEditFieldLabel = uilabel(app.UIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.fcoefficientsEditFieldLabel = uilabel(app.UIFigure);
            app.fcoefficientsEditFieldLabel.Text = 'fcoefficients';
            app.fcoefficientsEditField = uieditfield(app.UIFigure, 'text');
            app.fcoefficientsEditField.ValueChangedFcn = createCallbackFcn(app, @fcoefficientsEditFieldValueChanged, true);
            app.RootsValueListBoxLabel = uilabel(app.UIFigure);
            app.RootsValueListBoxLabel.Text = 'Roots Value';
            app.RootsValueListBox = uilistbox(app.UIFigure);
            app.RootsValueListBox.Items = {};
            app.RootsValueListBox.Value = {};
            app.Basin5ColorDropDownLabel = uilabel(app.UIFigure);
            app.Basin5ColorDropDownLabel.Text = 'Basin5Color';
            app.Basin5ColorDropDown = uidropdown(app.UIFigure);
            app.Basin5ColorDropDown.Items = {'black', 'blue', 'red', 'green', 'yellow', 'magenta', 'cyan'};
            app.Basin5ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @Basin5ColorDropDownValueChanged, true);
            app.Basin5ColorDropDown.Value = 'black';
            app.Basin6ColorDropDownLabel = uilabel(app.UIFigure);
            app.Basin6ColorDropDownLabel.Text = 'Basin6Color';
            app.Basin6ColorDropDown = uidropdown(app.UIFigure);
            app.Basin6ColorDropDown.Items = {'black', 'blue', 'red', 'green', 'yellow', 'magenta', 'cyan'};
            app.Basin6ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @Basin6ColorDropDownValueChanged, true);
            app.Basin6ColorDropDown.Value = 'black';
            app.Basin7ColorDropDownLabel = uilabel(app.UIFigure);
            app.Basin7ColorDropDownLabel.Text = 'Basin7Color';
            app.Basin7ColorDropDown = uidropdown(app.UIFigure);
            app.Basin7ColorDropDown.Items = {'black', 'blue', 'red', 'green', 'yellow', 'magenta', 'cyan'};
            app.Basin7ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @Basin7ColorDropDownValueChanged, true);
            app.Basin7ColorDropDown.Value = 'black';
            app.ElapsedtimeEditFieldLabel = uilabel(app.UIFigure);
            app.ElapsedtimeEditFieldLabel.Text = 'Elapsed time';
            app.ElapsedtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElapsedtimeEditField.Editable = 'off';
            app.fDerivationEditFieldLabel = uilabel(app.UIFigure);
            app.fDerivationEditFieldLabel.Text = 'fDerivation';
            app.fDerivationEditField = uieditfield(app.UIFigure, 'text');
            app.fDerivationEditField.Editable = 'off';
            app.MarkerSizeSliderLabel = uilabel(app.UIFigure);
            app.MarkerSizeSliderLabel.Text = 'MarkerSize';
            app.MarkerSizeSlider = uislider(app.UIFigure);
            app.MarkerSizeSlider.Limits = [1 10];
            app.MarkerSizeSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.MarkerSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeSliderValueChanged, true);
            app.MarkerSizeSlider.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = PlotBasinAttraction

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end